classdef espectromasas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        ImportacinTab          matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
        NumerodepicosLabel     matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        BuscarButton           matlab.ui.control.Button
        SensibilidadLabel      matlab.ui.control.Label
        SensibilidadEditField  matlab.ui.control.NumericEditField
        Lamp                   matlab.ui.control.Lamp
        NuevoButton            matlab.ui.control.Button
        ResultadosTab          matlab.ui.container.Tab
        CompuestosencontradosTextAreaLabel  matlab.ui.control.Label
        ResTextArea            matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ARCHIVOSButtonPushed(app, event)
           [filenamer, pathname]=uigetfile('*.png','Selección de imagen');
           if filenamer == 0
           return;
           end
            %im=imread(filename);
            %imshow(im);
            %winopen(filename);
            
       
         grabit(filename)
            %defaultFileName=fullfile(startingFolder,'*.png');
            %[filename, folder]=uigetfile(defaultFileName, 'selecciona una imagen');
            %if filename == 0
           % User clicked the Cancel button.
           %return;
           %end
           %fullFileName = fullfile(folder, filename);
           %imshow(fullFileName);
           
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
         
            value = app.EditField.Value;
            f = app.ImportacinTab;
global N
N=value %numero de picos

defaultRow={[],[]};
d = repmat(defaultRow, [value,1]);
columnformat = {'numeric', 'numeric'};
columneditable =  [true true];

global t


t = uitable(f,'Data',d,...
            'ColumnName', {'%rel','M/Z'},...
            'ColumnFormat', columnformat,...
            'ColumnEditable', columneditable,...
            'Position',[10,100,270,224]);
   
     
        end

        % Callback function: BuscarButton, ImportacinTab
        function BuscarButtonPushed(app, event)

     global t
      
          
     global tableData
     tableData = get(t, 'data')
    
    tableData=cell2mat(tableData)
  
    MasaB=transpose(tableData(:,2))
    PBR=transpose(tableData(:,1)) %Porcentaje relativo del usuario
    bar(app.UIAxes,MasaB,PBR,"BarWidth",0.1)
    %%%%%%%%%%
   global S
   global N
EELBD=cell(0,0) %compuestos encontrados en la base de datos
m=1 %Contador de base de datos
n=1 %Contador de datos de usuario
hle=1 %contador de hojas de libro

c=char('A'+(N-1))
cc=char('A'+N)
CONT=0 %Contador de ciclo while
z=1 %Contador de la dimension del vector resultado
Exceldatabase=sprintf("Exceldatabase_%i.xlsx",N)
while CONT==0
    MasaD=readmatrix(Exceldatabase,"Sheet",sprintf("%i",hle),"Range",sprintf('A1:%s1',c))
    PDR=readmatrix(Exceldatabase,"Sheet",sprintf("%i",hle),"Range",sprintf('A2:%s2',c))
a=find((MasaD(1,m)+S>=MasaB(1,n)) & (MasaD(1,m)-S<=(MasaB(1,n))))
b=find((PDR(1,m)+S>=PBR(1,n)) & (PBR(1,m)-S<=(PDR(1,n))))
a=numel(a)
b=numel(b)
if a==0 || b==0
    hle=hle+1
if prod(MasaD)==0 && numel(EELBD)==0 || prod(PDR)==0 && numel(EELBD)==0
   disp('No se encuentra en la base de datos') 
   set(app.ResTextArea,'value','El compuesto no se encuentra en la base de datos')
 
   app.Lamp.Color=[1,0,0];
   CONT=1
elseif prod(MasaD)==0 && numel(EELBD)~=0 || prod(PDR)==0 && numel(EELBD)~=0
     disp("Guardar codigo")
     disp("Fin")
 set(app.ResTextArea,'value','Compuesto encontrado')

     r = uitable(app.ResultadosTab,'Data',(EELBD'),...
            'ColumnName', {'Compuesto'},...
            'ColumnEditable', [false],...
            'Position',[20,120,270,224]);
   app.Lamp.Color=[0,1,0];
   
    CONT=1
end
elseif a~=0 && n>=N && prod(MasaD)==0 && b~=0 && n>=N && prod(PDR)==0 
   disp("Guardar codigo")
 
 ff=readcell(Exceldatabase,"Sheet",sprintf("%i",hle),"Range",sprintf('A1:%s1',cc))
    EELBD(1,z)=ff(1,N+1)
    NELBD(1,z)=hle
    disp("Fin")
    app.Lamp.Color=[0,1,0];
    set(app.ResTextArea,'value','Compuesto encontrado')
    
   %MasaD=readmatrix(Exceldatabase,"Sheet",sprintf("%i",hle),"Range",'A1:C1')
    r = uitable(app.ResultadosTab,'Data',(EELBD'),...
            'ColumnName', {'Compuesto'},...
            'ColumnEditable', [false],...
            'Position',[20,120,270,224]);
    
   CONT=1


elseif a~=0 && n>=N && prod(MasaD)~=0 && b~=0 && n>=N && prod(PDR)~=0
    ff=readcell(Exceldatabase,"Sheet",sprintf("%i",hle),"Range", sprintf('A1:%s1',cc))
    EELBD(1,z)=ff(1,N+1)
    NELBD(1,z)=hle
    disp("Guardar codigo")
  hle=hle+1
    z=z+1
elseif a~=0 && n<=N && b~=0 && n<=N
    n=n+1
    m=m+1
end
end


     %%%%%%%%%%
        end

        % Value changed function: SensibilidadEditField
        function SensibilidadEditFieldValueChanged(app, event)
   
           global S
            S = app.SensibilidadEditField.Value;
            
        end

        % Callback function
        function SECUENCIATabSizeChanged(app, event)
            position = app.SECUENCIATab.Position;
          
           
        end

        % Callback function
        function ResultadosButtonPushed(app, event)
         
        end

        % Callback function
        function ImportacinButtonPushed(app, event)
          
        end

        % Value changed function: ResTextArea
        function ResTextAreaValueChanged(app, event)
            value = app.ResTextArea.Value;
            
        end

        % Button pushed function: NuevoButton
        function NuevoButtonPushed(app, event)
            run('espectromasas.mlapp')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportacinTab = uitab(app.TabGroup);
            app.ImportacinTab.SizeChangedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.ImportacinTab.Title = 'Importación';
            app.UIAxes = uiaxes(app.ImportacinTab);
            title(app.UIAxes, 'FRAGMENTOGRAMA')
            xlabel(app.UIAxes, 'm/z')
            ylabel(app.UIAxes, '% rel')
            app.NumerodepicosLabel = uilabel(app.ImportacinTab);
            app.NumerodepicosLabel.Text = 'Numero de picos';
            app.EditField = uieditfield(app.ImportacinTab, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.BuscarButton = uibutton(app.ImportacinTab, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Text = 'Buscar';
            app.SensibilidadLabel = uilabel(app.ImportacinTab);
            app.SensibilidadLabel.Text = 'Sensibilidad (+/-)';
            app.SensibilidadEditField = uieditfield(app.ImportacinTab, 'numeric');
            app.SensibilidadEditField.Limits = [0 Inf];
            app.SensibilidadEditField.ValueChangedFcn = createCallbackFcn(app, @SensibilidadEditFieldValueChanged, true);
            app.Lamp = uilamp(app.ImportacinTab);
            app.NuevoButton = uibutton(app.ImportacinTab, 'push');
            app.NuevoButton.ButtonPushedFcn = createCallbackFcn(app, @NuevoButtonPushed, true);
            app.NuevoButton.Text = 'Nuevo';
            app.ResultadosTab = uitab(app.TabGroup);
            app.ResultadosTab.Title = 'Resultados';
            app.CompuestosencontradosTextAreaLabel = uilabel(app.ResultadosTab);
            app.CompuestosencontradosTextAreaLabel.Text = 'Compuestos encontrados';
            app.ResTextArea = uitextarea(app.ResultadosTab);
            app.ResTextArea.ValueChangedFcn = createCallbackFcn(app, @ResTextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = espectromasas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end